classdef BAR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Log                    matlab.ui.control.TextArea
        TabGroup               matlab.ui.container.TabGroup
        DatabaseSearchTab      matlab.ui.container.Tab
        LoadFilesButton        matlab.ui.control.Button
        FindFilesButton        matlab.ui.control.Button
        TableFiles             matlab.ui.control.Table
        SearchTags             matlab.ui.control.EditField
        EditFieldLabel         matlab.ui.control.Label
        EquipmentDropDown      matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown  matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        Directory              matlab.ui.control.EditField
        SelectDirectoryButton  matlab.ui.control.Button
        AnalysisTab            matlab.ui.container.Tab
        ReportingTab           matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)

            m_files = dir(m_directory);
            m_tags = app.SearchTags.Value;
            if ~isempty(m_tags)
                m_tags = textscan(m_tags,'%s','Delimiter',',');
            end

            for i = 3:length(m_files)
                if isfolder([m_files(i).folder '\' m_files(i).name])
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        n = length(files.name);
                    end
                    [~, ~, ext] = fileparts(m_files(i).name);
                    if strcmp(ext, app.FileExtensionDropDown.Value) && contains(m_files(i).name, m_tags{1})
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes;
                    end
                end
            end
            
        end
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['file search started'];
                case '002'
                    text{length(text) + 1,1} = ['file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' MB total'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            % Allow the user to select the parent directory.
            m_path = uigetdir(pwd,'Please select the data directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            app.Directory.Value = m_path;
        end

        % Button pushed function: FindFilesButton
        function FindFilesButtonPushed(app, event)
            
            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTags.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            files = filesearch(app, files, m_directory);
            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);

        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            data = struct;
            ind = 1;

            for i = 1:height(files)
                switch [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value]
                    case 'h5APDM'
                        data.(files(i).name) = load_h5_APDM([files.folder{i} '\' files.name{i}]);
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.SelectDirectoryButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.Directory = uieditfield(app.DatabaseSearchTab, 'text');
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {'h5'};
            app.FileExtensionDropDown.Value = 'h5';
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {'APDM'};
            app.EquipmentDropDown.Value = 'APDM';
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTags = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableFiles.RowName = {};
            app.FindFilesButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FindFilesButtonPushed, true);
            app.FindFilesButton.Text = 'Find Files';
            app.LoadFilesButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ReportingTab = uitab(app.TabGroup);
            app.ReportingTab.Title = 'Reporting';
            app.Log = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end